classdef Behaviour_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BehaviourToolboxUIFigure        matlab.ui.Figure
        LoadExistingAnalysisButton      matlab.ui.control.Button
        StartNewAnalysisButton          matlab.ui.control.Button
        SaveAnalysisButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SelectBrowseROIsButton          matlab.ui.control.Button
        ShowAnalyseButton               matlab.ui.control.Button
        RecalculateallCheckBox          matlab.ui.control.CheckBox
        UpdateTableButton               matlab.ui.control.Button
        PauseforeachexperimentCheckBox  matlab.ui.control.CheckBox
        ExperimentsListBoxLabel         matlab.ui.control.Label
        ExperimentsListBox              matlab.ui.control.ListBox
        RefreshFilelistButton           matlab.ui.control.Button
        ExportToMatlabanalysisButton    matlab.ui.control.Button
        RecoverinterruptedanalysisButton  matlab.ui.control.StateButton
        IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel  matlab.ui.control.Label
        OpenFolderButton                matlab.ui.control.Button
        OpenVideoButton                 matlab.ui.control.Button
        TopfolderEditFieldLabel         matlab.ui.control.Label
        TopfolderEditField              matlab.ui.control.EditField
    end


    properties (Access = public)
        Experiment_set % Description
        failed_analysis
        failed_loading
        splitvideo
        full_table
        expe_table
        current_selection_file
        current_selection_folder
        current_selection_idx
        videotypes
    end


    methods (Access = private)
    
        function enable_analysis(app, status)
            if status
                status = 'On';
            else
                status = 'Off';
            end
            
            app.UITable.Enable = status;
            app.SelectBrowseROIsButton.Enable = status;
            app.ShowAnalyseButton.Enable = status;
            app.RecalculateallCheckBox.Enable = status;
            app.SaveAnalysisButton.Enable = status;
            app.UpdateTableButton.Enable = status;
            app.PauseforeachexperimentCheckBox.Enable = status;
            app.ExperimentsListBoxLabel.Enable = status;
            app.ExperimentsListBox.Enable = status;
            app.ExportToMatlabanalysisButton.Enable = 'Off';%status;
        end

        function [filter_list, selection] = get_subset(app)
            n_types         = numel(app.videotypes);
            if numel(app.ExperimentsListBox.Value) ==  numel(app.ExperimentsListBox.Items)
                filter_list = '';
                selection = app.ExperimentsListBox.Value;
            else
                selection = app.ExperimentsListBox.Value;
                match = ismember(app.full_table(:,n_types+6),selection);
                list = app.full_table(match,1:2);
                filter_list = unique(strrep(join(strcat(list,'/'), 2), ' ', ''));
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, backup)

            if nargin == 2
                if ischar(backup) && isfile(backup)
                    app.Experiment_set = load(backup);   
                elseif ischar(backup)
                    fprintf('backup file not found. did you st an absolut path\n?')
                elseif isa(backup, 'Analysis_Set')
                    app.Experiment_set = backup;                 
                else
                    fprinf('objct passed is not of Analysis_Set class\n?')
                    return
                end
                if isfield(app.Experiment_set, 'analysis')
                    app.Experiment_set = app.Experiment_set.analysis; 
                elseif isfield(app.Experiment_set, 'my_analysis')
                    app.Experiment_set = app.Experiment_set.my_analysis; 
                end                
                app.enable_analysis(true);
                app.UpdateTableButtonPushed();
                assignin('base', 'behaviour_GUI', app);
            end 
        end

        % Button pushed function: LoadExistingAnalysisButton
        function LoadExistingAnalysisButtonPushed(app, event)
            [selection_file, selection_folder] = uigetfile('Load previous analysis','*.mat');
            if any(selection_file)
                app.Experiment_set = load([selection_folder, '/', selection_file]); 
                if isfield(app.Experiment_set, 'analysis')
                    app.Experiment_set = app.Experiment_set.analysis; 
                elseif isfield(app.Experiment_set, 'my_analysis')
                    app.Experiment_set = app.Experiment_set.my_analysis; 
                end
                app.enable_analysis(true);
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: StartNewAnalysisButton
        function StartNewAnalysisButtonPushed(app, event)
            selection = uigetdir('', 'Select Video Folder');
            app.enable_analysis(false);
            if any(selection)
                app.enable_analysis(true);
                app.Experiment_set = [];
                app.ExperimentsListBox.Items = {};
                app.Experiment_set = Analysis_Set([selection, '\']);
                app.Experiment_set.update();
                app.TopfolderEditField.Value = [selection, '\'];
                app.SelectBrowseROIsButton.Enable = 'On';
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            my_analysis        = app.Experiment_set;
            uisave({'my_analysis'},['saved_analysis ',strrep(datestr(datetime('now')),':','_')]);
        end

        % Button pushed function: SelectBrowseROIsButton
        function SelectBrowseROIsButtonPushed(app, event)
            if isempty(app.Experiment_set.video_folder)
                app.StartNewAnalysisButtonPushed();
                if ~isfolder(app.Experiment_set.video_folder)
                    error('You need to set a valid video_folder to extract results or set ROIs')
                end
            end
            
            %% Get any subset of experiments that would be selected
            [filter_list, selection] = get_subset(app);
            
            %% Select result ROI for the current selection
            app.Experiment_set.select_ROIs(filter_list);

            if isempty(app.Experiment_set)
                error_box('Unable to load the selection. You may have moved the files. Try Refresh File List')
                return
            end
            
            %% Update full data table with any new values
            app.enable_analysis(true);
            app.UpdateTableButtonPushed();
            
            %% If there was an initial selection ,resore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
            assignin('base', 'behaviour_GUI', app);
        end

        % Button pushed function: ShowAnalyseButton
        function ShowAnalyseButtonPushed(app, event)
            %% Get any subset of experiments that would be selected
            [filter_list, selection] = get_subset(app);
            
            %% Measure result for the current selection
            if app.PauseforeachexperimentCheckBox.Value
                display = 'pause';
            else
                display = true;
            end
            
            if isdir(app.Experiment_set.video_folder)
                need_update = app.Experiment_set.analyse(filter_list, app.RecalculateallCheckBox.Value, display);  
                if need_update % if anything was updated, update the table too.
                    app.UpdateTableButtonPushed();
                end
            else
                error_box('The folder containing the videos does not seem to exist. Missing data will not be extracted. showing what is currenlty available only',1)
                if ~isempty(filter_list)
                    analysed_idx   	= find(contains({app.Experiment_set.experiments.path}, filter_list));
                else
                    analysed_idx   	= 1:app.Experiment_set.n_expe;
                end
                
                for experiment_idx = analysed_idx
                    app.Experiment_set.experiments(experiment_idx).analyse(false, display);
                end
            end
            
            %% If there was an initial selection, restore it
            if ~isempty(filter_list)
                app.ExperimentsListBox.Value = selection;
                app.ExperimentsListBoxValueChanged();
            end
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            app.TopfolderEditField.Value = app.Experiment_set.video_folder;
            if ~isfolder(app.TopfolderEditField.Value)
                app.TopfolderEditField.BackgroundColor = [1,0.5,0.5];
            end
            app.full_table  = {};
            app.expe_table  = {};
            app.videotypes  = erase(app.Experiment_set.videotypes,'-1');
            n_types         = numel(app.videotypes);
            app.UITable.ColumnName = [{'Day'},{'Experiment'},{'Recording'},app.videotypes,{'Selected result'},{'analysed?'}]';
            
            for exp = 1:app.Experiment_set.n_expe
                current_experiment = app.Experiment_set.experiments(exp);
                exp_name = strsplit(current_experiment.path,'/');
                exp_name = strrep(join(strcat(exp_name(end-2:end),'/')), ' ', '');
                last_line_idx = size(app.full_table, 1);
                
                %% Update experiment list
                app.expe_table(end+1,1) = exp_name;
                
                %% Update recordings list
                for recording_idx = 1:current_experiment.n_rec
                    new_line_idx = last_line_idx+recording_idx;
                    current_recording = current_experiment.recordings(recording_idx);
                    name = strsplit(current_recording.path,{'/','\'});
                    app.full_table(new_line_idx,1:3) = [name(end-3), name(end-2), {name{end-1}(8:15)}];                    
                    if current_recording.n_vid
                        app.full_table{new_line_idx,n_types + 4} = repmat(-1, 1, numel(current_experiment.videotypes)); % missing videos will stay at -1
                        for video_type_idx = 1:current_recording.n_vid
                            real_idx = find(contains({current_recording.videos.path}, current_recording.videotypes{video_type_idx}));
                            if ~isempty(real_idx)
                                current_video = current_recording.videos(real_idx);
                                name = strsplit(current_recording.videos(real_idx).path,{'/','\'});
                                gui_video_type_idx = find(cellfun(@(x) contains(current_video.video_types,x),  app.UITable.ColumnName(4:(4+n_types-1))'));
                                app.full_table{new_line_idx,n_types + 4}(gui_video_type_idx) = numel(current_video.ROI_location);%numel(current_video.extracted_results);

                                for type = 1:numel(app.videotypes)
                                    if contains(name(end), app.videotypes{type})
                                        app.full_table(new_line_idx,3+type) = {any([app.full_table{new_line_idx,3+type}, 1])};
                                    else
                                        app.full_table(new_line_idx,3+type) = {any([app.full_table{new_line_idx,3+type}, 0])};
                                    end
                                end
                                app.full_table(new_line_idx,n_types + 6) = exp_name; %to match expe_table
                            end                            
                        end
                    else
                        app.full_table(new_line_idx,1:3) = [name(end-3), name(end-2), {name{end-1}(8:15)}];  
                        app.full_table(new_line_idx,4:(4+n_types-1)) = {false};
                        app.full_table{new_line_idx,n_types + 4} = repmat(-1, 1, numel(current_experiment.videotypes));
                        app.full_table(new_line_idx,n_types + 6) = exp_name; %to match expe_table
                        fprintf([current_recording.path,' seems empty. consider cleaning folder\n'])
                    end
                    app.full_table{new_line_idx,n_types + 5} = current_recording.analysed;
                end
            end
            if ~isempty(app.full_table)
                invalid = cellfun(@isempty, app.full_table(:,n_types + 6));
                app.full_table = app.full_table(~invalid,:); %% QQ CHECK WHY
                app.full_table(:,n_types + 4) = cellfun(@(x) num2str(x),app.full_table(:,n_types + 4), 'UniformOutput', false);
                app.UITable.Data = app.full_table(:,1:(n_types + 5));
            else
                app.UITable.Data = [];
            end
            
            app.ExperimentsListBox.Items = app.expe_table;
            app.ExperimentsListBoxValueChanged();
        end

        % Value changed function: ExperimentsListBox
        function ExperimentsListBoxValueChanged(app, event)
            value           = app.ExperimentsListBox.Value;
            n_types         = numel(app.videotypes);
            match           = ismember(app.full_table(:,n_types + 6),value);
            app.UITable.Data= app.full_table(match,1:(n_types + 5));
        end

        % Button pushed function: RefreshFilelistButton
        function RefreshFilelistButtonPushed(app, event)
            app.Experiment_set.update();
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: ExportToMatlabanalysisButton
        function ExportToMatlabanalysisButtonPushed(app, event)
            export_folder   = uigetdir(app.Experiment_set.video_folder, 'Select Top folder');
            n_types         = numel(app.videotypes);
            
            if ~isempty(export_folder) && any(export_folder)
                [~, export_expe_folders] = list_expe_folders(export_folder);
                
                for video_expe_folder = app.ExperimentsListBox.Value     
                    current_video_id    = find(strcmp(app.ExperimentsListBox.Items, video_expe_folder));
                    video_expe_folder   = parse_paths(video_expe_folder{1});
                    id                  = find(contains(export_expe_folders, video_expe_folder));
                    
                    if ~isempty(id)
                        %% Get list of data folder on the export side
                        [~, export_data_folders] = list_data_folders(export_expe_folders{id});
                        exported                 = cell(size(export_data_folders));
                        exported_source          = cell(size(export_data_folders));
                        
                        %% Get list of videos
                        match = contains(app.full_table(:,n_types+6),video_expe_folder);
                        list = app.full_table(match,3);
                        short_export_list = cellfun(@(x) x(end-8:end-1), export_data_folders, 'UniformOutput', false);
                        
                        %% Estimate average time error between the 2 computers
                        err = [];
                        for video_rec_nb = 1:numel(list')
                            rec = list(video_rec_nb);
                            [err(video_rec_nb)] = min(abs(datenum(short_export_list,'HH-MM-SS/') - datenum(rec,'HH_MM_SS')));                            
                        end
                        err = round(24*60*60*nanmedian(err), 3);
                        fprintf(['\n#######################################\n'])
                        fprintf(['Now extracting video data for experiment ',video_expe_folder,'.\n'])
                        fprintf(['Average time offset between the two computers is ',num2str(err),' seconds. Any delay >+10s or <-10s around this value will considered as a different recording.\n'])

                        for video_rec_nb = 1:numel(list')
                            rec = list(video_rec_nb);
                            [v, best] = min(abs(datenum(short_export_list,'HH-MM-SS') - datenum(rec,'HH_MM_SS')));
                            local_err = round(24*60*60*abs(v), 3);
                            [export_data_folders{best}, rec, local_err]';
                            
                            
                            results = {};
                            roi_labels= {};
                            source  = {};
                            for video_type = app.Experiment_set.experiments(current_video_id).videotypes
                                real_idx = find(strcmp(app.Experiment_set.experiments(current_video_id).recordings(video_rec_nb).videotypes, video_type{1}));
                                if ~isempty(real_idx)
                                    results{real_idx} = cat(3, app.Experiment_set.experiments(current_video_id).recordings(video_rec_nb).videos(real_idx).extracted_results{:});
                                    roi_labels{real_idx}= app.Experiment_set.experiments(current_video_id).recordings(video_rec_nb).videos(real_idx).roi_labels;
                                    source{real_idx}  = app.Experiment_set.experiments(current_video_id).recordings(video_rec_nb).videotypes{real_idx};
                                    %figure(123);cla();plot(squeeze(results{video_type}(:,2,:)), squeeze(results{video_type}(:,1,:)));
                                    %pause(0.3);
                                else
                                    fprintf(['Good folder match for ',[video_expe_folder, list{video_rec_nb}],' but at least one video (',video_type{1},') seems missing for this recording\n'])

                                end
                            end
                            if local_err < err + 9
                                exported{best} = [exported{best} ; export_data_folders{best}];
                                exported_source{best} = [exported_source{best} ; list{video_rec_nb}];
                                save([export_data_folders{best}, 'extracted_result.mat'],'results','roi_labels', 'source');
                            else
                                fprintf(['No good match for ',[video_expe_folder, list{video_rec_nb}],'. Delay reached ',num2str(round(local_err, 2)),'s . The recording was probably deleted. Consider removing the video too\n'])
                            end
                        end                        
                    end
                    missing = export_data_folders(cellfun(@isempty, exported));
                    if numel(missing) > 1
                        fprintf('WARNING : the following data_folders have no video record\n');
                        vertcat(missing{2:end})
                    end  
                    if any(cellfun(@(x) size(x,1), exported) > 1)
                        fprintf('WARNING : DOUBLE EXPORT.\n');
                        exported{cellfun(@(x) size(x,1), exported) > 1}
                        export_data_folders{cellfun(@(x) size(x,1), export_data_folders) > 1}
                    end
                end
            end
        end

        % Value changed function: RecoverinterruptedanalysisButton
        function RecoverinterruptedanalysisButtonValueChanged(app, event)
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            winopen(app.current_selection_folder);
        end

        % Button pushed function: OpenVideoButton
        function OpenVideoButtonPushed(app, event)
            if isempty(app.current_selection_idx)
                error_box('highlight the video type you want to open')
            else                
                if isfile(app.current_selection_file{app.current_selection_idx})
                    winopen(app.current_selection_file{app.current_selection_idx});
                end   
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            fold = [app.Experiment_set.video_folder,strjoin(app.UITable.Data(event.Indices(1),1:2),'/'),'/*',app.UITable.Data{event.Indices(1),3},'*/'];
            files = dir([fold, '**/*.avi']);
            fold = files(1).folder;            
            app.current_selection_file = cellfun(@(a,b) strjoin({a,b},'/'), {files.folder}', {files.name}', 'UniformOutput', false);
            app.current_selection_folder = fold;
            idx = event.Indices(2)-3;
            if idx < 1 || idx > 3
                idx = 3;
            end
            list = app.UITable.ColumnName(4:6)';
            app.current_selection_idx = find(contains(app.current_selection_file, list{idx}));
        end

        % Value changed function: TopfolderEditField
        function TopfolderEditFieldValueChanged(app, event)
            if ~isfolder(app.TopfolderEditField.Value)
                app.TopfolderEditField.BackgroundColor = [1,0.5,0.5];
            else
                app.Experiment_set.video_folder = app.TopfolderEditField.Value;
                app.TopfolderEditField.BackgroundColor = [1,1,1];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BehaviourToolboxUIFigure = uifigure;
            app.BehaviourToolboxUIFigure.Name = 'Behaviour Toolbox';
            app.LoadExistingAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.LoadExistingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingAnalysisButtonPushed, true);
            app.LoadExistingAnalysisButton.Text = 'Load Existing Analysis';
            app.StartNewAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.StartNewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewAnalysisButtonPushed, true);
            app.StartNewAnalysisButton.Text = 'Start New Analysis';
            app.SaveAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Enable = 'off';
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.UITable = uitable(app.BehaviourToolboxUIFigure);
            app.UITable.ColumnName = {'Day'; 'Experiment'; 'Recording'; 'Eye'; 'Body'; 'Whisker'; 'selected MI'; 'analysed'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectBrowseROIsButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SelectBrowseROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBrowseROIsButtonPushed, true);
            app.SelectBrowseROIsButton.Enable = 'off';
            app.SelectBrowseROIsButton.Text = 'Select/Browse ROIs';
            app.ShowAnalyseButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.ShowAnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnalyseButtonPushed, true);
            app.ShowAnalyseButton.Enable = 'off';
            app.ShowAnalyseButton.Text = 'Show/Analyse';
            app.RecalculateallCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.RecalculateallCheckBox.Enable = 'off';
            app.RecalculateallCheckBox.Text = 'Recalculate all';
            app.UpdateTableButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Enable = 'off';
            app.UpdateTableButton.Text = 'Update Table';
            app.PauseforeachexperimentCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.PauseforeachexperimentCheckBox.Enable = 'off';
            app.PauseforeachexperimentCheckBox.Text = 'Pause for each experiment';
            app.ExperimentsListBoxLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBoxLabel.Enable = 'off';
            app.ExperimentsListBoxLabel.Text = 'Experiments';
            app.ExperimentsListBox = uilistbox(app.BehaviourToolboxUIFigure);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.Multiselect = 'on';
            app.ExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentsListBoxValueChanged, true);
            app.ExperimentsListBox.Enable = 'off';
            app.ExperimentsListBox.Value = {};
            app.RefreshFilelistButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.RefreshFilelistButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshFilelistButtonPushed, true);
            app.RefreshFilelistButton.Text = 'Refresh File list';
            app.ExportToMatlabanalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.ExportToMatlabanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToMatlabanalysisButtonPushed, true);
            app.ExportToMatlabanalysisButton.Text = 'Export To Matlab analysis';
            app.RecoverinterruptedanalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'state');
            app.RecoverinterruptedanalysisButton.ValueChangedFcn = createCallbackFcn(app, @RecoverinterruptedanalysisButtonValueChanged, true);
            app.RecoverinterruptedanalysisButton.Text = 'Recover interrupted analysis';
            app.IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.IfyoupressedCtrlCbutwantrecoverwhateverwasalreadyanalysedLabel.Text = {'If you pressed Ctrl+C but want '; 'recover whatever was already'; ' analysed'};
            app.OpenFolderButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Text = 'Open Folder';
            app.OpenVideoButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.OpenVideoButton.ButtonPushedFcn = createCallbackFcn(app, @OpenVideoButtonPushed, true);
            app.OpenVideoButton.Text = 'Open Video';
            app.TopfolderEditFieldLabel = uilabel(app.BehaviourToolboxUIFigure);
            app.TopfolderEditFieldLabel.Text = 'Top folder';
            app.TopfolderEditField = uieditfield(app.BehaviourToolboxUIFigure, 'text');
            app.TopfolderEditField.ValueChangedFcn = createCallbackFcn(app, @TopfolderEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Behaviour_analysis(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BehaviourToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BehaviourToolboxUIFigure)
        end
    end
end